classdef PIV_db_analyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
    end

    
    properties (Access = private)
        parent_app % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             pos_ij_arr = app.parent_app.m.Get_pos_ij_arr__of_selected_point();
            if isempty(pos_ij_arr)
                app.Plot_time_series_pos_ij_arr.Value = {'Точки не выбраны'};
                return
            end
            pos =        app.parent_app.m.Get_pos_by_pos_ij_arr( pos_ij_arr );
            text = cell(size(pos_ij_arr,1),1);
            for i = 1:size(pos_ij_arr,1)
                pos_i = pos_ij_arr(i,1);
                pos_j = pos_ij_arr(i,2);                
                x_px = pos(i,1);
                y_px = pos(i,2);
                x_m  = pos(i,3);
                y_m  = pos(i,4);
                text{i} = sprintf(...
                    'i %d, j %d, (%d, %d) px, (%.3f, %.3f) м',...
                    pos_i, pos_j,...
                    x_px, y_px,...
                    x_m, y_m);
            end
            app.Plot_time_series_pos_ij_arr.Value  = text;
            figure();
            app.parent_app.m.Plot_time_series( pos_ij_arr  );
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.parent_app.m.Update_mean_field();
            app.parent_app.m.Redraw_fields();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить временные реализации для выбранных точек';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Вычислить среднее поле';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIV_db_analyze(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end