classdef PIV_db_export < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        fig_im_one                     matlab.ui.control.Button
        fig_im_all                     matlab.ui.control.Button
        fig_vid_all                    matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        fig_vid_all_fps                matlab.ui.control.Spinner
        comment                        matlab.ui.control.Button
        Export_all_fields_to_one_file  matlab.ui.control.Button
        Export_all_fields_to_separate_files  matlab.ui.control.Button
        Export_time_series             matlab.ui.control.Button
        Export_mean_field_to_files     matlab.ui.control.Button
        Export_mean_field_to_files_profiles  matlab.ui.control.CheckBox
        Label_2                        matlab.ui.control.Label
        Export_time_series_sp          matlab.ui.control.TextArea
        Export_mean_field_to_files_profiles_2  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        parent_app % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
        end

        % Button pushed function: fig_im_one
        function fig_im_onePushed(app, event)
            defname = sprintf('output\\figure %s.png', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*.fig;*.png;*.jpg;*.bmp','Выбор имени файла', defname );
            if file == 0, return; end
            selpath = fullfile(path,file);
            app.parent_app.m.Export_figure_current(selpath);
        end

        % Button pushed function: fig_im_all
        function fig_im_allButtonPushed(app, event)
            defname = sprintf('output\\figures %s ~info~.png', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*.fig;*.png;*.jpg;*.bmp','Выбор имени файла', defname );
            if file == 0, return; end
            if ~contains(file,'~info~')
                msgbox('Имя файла должно содержать подстроку ~info~');
                return;
            end
            selpath = fullfile(path,file);
            app.parent_app.m.Export_figure_all(selpath);
        end

        % Button pushed function: fig_vid_all
        function fig_vid_allButtonPushed(app, event)
            defname = sprintf('output\\video %s.avi', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*.avi','Выбор имени файла', defname );
            if file == 0, return; end
            selpath = fullfile(path,file);
            app.parent_app.m.Export_figure_all_video( selpath, app.fig_vid_all_fps.Value );
        end

        % Button pushed function: comment
        function commentButtonPushed(app, event)
            defname = sprintf('output\\comment %s.txt', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*.txt','Выбор имени файла', defname );
            if file == 0, return; end
            selpath = fullfile(path,file);
            app.parent_app.m.Export_comment(selpath);
        end

        % Button pushed function: Export_all_fields_to_one_file
        function Export_all_fields_to_one_fileButtonPushed(app, event)
            defname = sprintf('output\\fields %s.dat', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*','Выбор имени файла', defname );
            if file == 0, return; end
            selpath = fullfile(path,file);
            app.parent_app.m.Export_all_fields_to_one_file(selpath);
        end

        % Button pushed function: Export_all_fields_to_separate_files
        function Export_all_fields_to_separate_filesButtonPushed(app, event)
            defname = sprintf('output\\fields %s ~info~.dat', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*','Выбор имени файла', defname );
            if file == 0, return; end
            if ~contains(file,'~info~')
                msgbox('Имя файла должно содержать подстроку ~info~');
                return;
            end
            selpath = fullfile(path,file);
            app.parent_app.m.Export_all_fields_to_separate_files(selpath);
        end

        % Button pushed function: Export_time_series
        function Export_time_seriesButtonPushed(app, event)
            defname = sprintf('output\\time series %s ~info~.dat', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*','Выбор имени файла', defname );
            if file == 0, return; end
            if ~contains(file,'~info~')
                msgbox('Имя файла должно содержать подстроку ~info~');
                return;
            end
            selpath = fullfile(path,file);
            
            pos_ij_arr = app.parent_app.m.Get_pos_ij_arr__of_selected_point();
            if isempty(pos_ij_arr)
                app.Plot_time_series_pos_ij_arr.Value = {'Точки не выбраны'};
                return
            end
            pos =        app.parent_app.m.Get_pos_by_pos_ij_arr( pos_ij_arr );
            text = cell(size(pos_ij_arr,1),1);
            for i = 1:size(pos_ij_arr,1)
                pos_i = pos_ij_arr(i,1);
                pos_j = pos_ij_arr(i,2);
                x_px = pos(i,1);
                y_px = pos(i,2);
                x_m  = pos(i,3);
                y_m  = pos(i,4);
                text{i} = sprintf(...
                    'i %d, j %d, (%d, %d) px, (%.3f, %.3f) м',...
                    pos_i, pos_j,...
                    x_px, y_px,...
                    x_m, y_m);
            end
            app.Export_time_series_sp.Value  = text;
            
            app.parent_app.m.Export_time_series( selpath, pos_ij_arr );
        end

        % Button pushed function: Export_mean_field_to_files
        function Export_mean_field_to_filesButtonPushed(app, event)
            defname = sprintf('output\\mean field %s ~info~.dat', datestr(now, 'yyyy-mm-dd HH-MM-SS'));
            [file,path] = uiputfile( '*','Выбор имени файла', defname );
            if file == 0, return; end
            
            selpath = fullfile(path,file);
            
            
            profile_y__pos_i = [];
            profile_x__pos_j = [];
            pos_ij_arr = app.parent_app.m.Get_pos_ij_arr__of_selected_point();
            
            if app.Export_mean_field_to_files_profiles.Value || app.Export_mean_field_to_files_profiles_2.Value
                if ~contains(file,'~info~')
                    msgbox('Имя файла должно содержать подстроку ~info~');
                    return;
                end
            end
            
            if app.Export_mean_field_to_files_profiles.Value                
                profile_y__pos_i = pos_ij_arr(:,1);
            end
            if app.Export_mean_field_to_files_profiles_2.Value
                profile_x__pos_j = pos_ij_arr(:,2);
            end
            app.parent_app.m.Export_mean_field_to_files( selpath, profile_y__pos_i, profile_x__pos_j );
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Экспорт';
            app.fig_im_one = uibutton(app.UIFigure, 'push');
            app.fig_im_one.ButtonPushedFcn = createCallbackFcn(app, @fig_im_onePushed, true);
            app.fig_im_one.Text = 'Сохранить поле как изображение';
            app.fig_im_all = uibutton(app.UIFigure, 'push');
            app.fig_im_all.ButtonPushedFcn = createCallbackFcn(app, @fig_im_allButtonPushed, true);
            app.fig_im_all.Text = 'Сохранить все поля как изображения';
            app.fig_vid_all = uibutton(app.UIFigure, 'push');
            app.fig_vid_all.ButtonPushedFcn = createCallbackFcn(app, @fig_vid_allButtonPushed, true);
            app.fig_vid_all.Text = 'Сохранить все поля как видео';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Частота кадров в видео';
            app.fig_vid_all_fps = uispinner(app.UIFigure);
            app.fig_vid_all_fps.Limits = [0.1 Inf];
            app.fig_vid_all_fps.Value = 5;
            app.comment = uibutton(app.UIFigure, 'push');
            app.comment.ButtonPushedFcn = createCallbackFcn(app, @commentButtonPushed, true);
            app.comment.Text = 'Сохранить описание как текст';
            app.Export_all_fields_to_one_file = uibutton(app.UIFigure, 'push');
            app.Export_all_fields_to_one_file.ButtonPushedFcn = createCallbackFcn(app, @Export_all_fields_to_one_fileButtonPushed, true);
            app.Export_all_fields_to_one_file.Text = 'Сохранить все поля в единый файл';
            app.Export_all_fields_to_separate_files = uibutton(app.UIFigure, 'push');
            app.Export_all_fields_to_separate_files.ButtonPushedFcn = createCallbackFcn(app, @Export_all_fields_to_separate_filesButtonPushed, true);
            app.Export_all_fields_to_separate_files.Text = 'Сохранить все поля в набор файлов';
            app.Export_time_series = uibutton(app.UIFigure, 'push');
            app.Export_time_series.ButtonPushedFcn = createCallbackFcn(app, @Export_time_seriesButtonPushed, true);
            app.Export_time_series.Text = 'Сохранить временные реализации';
            app.Export_mean_field_to_files = uibutton(app.UIFigure, 'push');
            app.Export_mean_field_to_files.ButtonPushedFcn = createCallbackFcn(app, @Export_mean_field_to_filesButtonPushed, true);
            app.Export_mean_field_to_files.Text = 'Сохранить среднее поле';
            app.Export_mean_field_to_files_profiles = uicheckbox(app.UIFigure);
            app.Export_mean_field_to_files_profiles.Text = 'Сохранять вертикальные профили';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Выбранные точки:'; ''};
            app.Export_time_series_sp = uitextarea(app.UIFigure);
            app.Export_time_series_sp.Editable = 'off';
            app.Export_mean_field_to_files_profiles_2 = uicheckbox(app.UIFigure);
            app.Export_mean_field_to_files_profiles_2.Text = 'Сохранять горизонтальные профили';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_export(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end