classdef PIV_db_fi_params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button_ok                       matlab.ui.control.Button
        Button_cancel                   matlab.ui.control.Button
        Reset                           matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        CC_ratio_limits1                matlab.ui.control.NumericEditField
        CC_ratio_limits2                matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        CC_maxRaitio_max                matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        mean_im1_limits1                matlab.ui.control.NumericEditField
        mean_im1_limits2                matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        xd_limits1                      matlab.ui.control.NumericEditField
        xd_limits2                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        yd_limits1                      matlab.ui.control.NumericEditField
        yd_limits2                      matlab.ui.control.NumericEditField
        EditField_11Label               matlab.ui.control.Label
        d_limits1                       matlab.ui.control.NumericEditField
        d_limits2                       matlab.ui.control.NumericEditField
        median_space__on                matlab.ui.control.CheckBox
        Label_5                         matlab.ui.control.Label
        median_space__window_size1      matlab.ui.control.Spinner
        median_space__window_size2      matlab.ui.control.Spinner
        Label_6                         matlab.ui.control.Label
        median_space__max_d_difference  matlab.ui.control.NumericEditField
        interp_space__on                matlab.ui.control.CheckBox
        Label_8                         matlab.ui.control.Label
        interp_space__Method            matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        interp_space__ExtrapolationMethod  matlab.ui.control.DropDown
        median_time__on                 matlab.ui.control.CheckBox
        Label_11                        matlab.ui.control.Label
        median_time__dynamic__window_size  matlab.ui.control.Spinner
        Label_12                        matlab.ui.control.Label
        median_time__max_d_difference   matlab.ui.control.NumericEditField
        local_filtering__on             matlab.ui.control.CheckBox
        median_time__dynamic__on        matlab.ui.control.CheckBox
        interp_time__on                 matlab.ui.control.CheckBox
        Label_13                        matlab.ui.control.Label
        interp_time__Method             matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        interp_time__max_gap_length     matlab.ui.control.Spinner
    end


    properties (Access = private)
        parent_app
        fi_params_backup Filter_and_interpolation_params
    end

    methods (Access = private)
    
        function Load_values(app)
            fi_params = app.parent_app.m.p.fi;
            
            app.local_filtering__on.Value = fi_params.local_filtering__on;
            app.CC_ratio_limits1.Value = fi_params.CC_ratio_limits(1);
            app.CC_ratio_limits2.Value = fi_params.CC_ratio_limits(2);
            app.CC_maxRaitio_max.Value = fi_params.CC_maxRaitio_max;
            app.mean_im1_limits1.Value = fi_params.mean_im1_limits(1);
            app.mean_im1_limits2.Value = fi_params.mean_im1_limits(2);
            app.xd_limits1.Value = fi_params.xd_limits(1);
            app.xd_limits2.Value = fi_params.xd_limits(2);
            app.yd_limits1.Value = fi_params.yd_limits(1);
            app.yd_limits2.Value = fi_params.yd_limits(2);
            app.d_limits1.Value = fi_params.d_limits(1);
            app.d_limits2.Value = fi_params.d_limits(2);
            
            %медианная фильтрация по пространству:
            app.median_space__on.Value = fi_params.median_space__on;
            app.median_space__window_size1.Value = fi_params.median_space__window_size(1);
            app.median_space__window_size2.Value = fi_params.median_space__window_size(2);
            app.median_space__max_d_difference.Value = fi_params.median_space__max_d_difference;
            
            %медианная фильтрация по времени:
            app.median_time__on.Value = fi_params.median_time__on;            
            app.median_time__max_d_difference.Value = fi_params.median_time__max_d_difference;
            app.median_time__dynamic__on.Value = fi_params.median_time__dynamic__on;
            app.median_time__dynamic__window_size.Value = fi_params.median_time__dynamic__window_size;
            
            %% Интерполяция:
            app.interp_space__on.Value = fi_params.interp_space__on;
            app.interp_space__Method.Items = { 'linear', 'nearest', 'natural' };
            app.interp_space__Method.Value = fi_params.interp_space__Method;
            app.interp_space__ExtrapolationMethod.Items = { 'nearest', 'linear',  'none' };
            app.interp_space__ExtrapolationMethod.Value = fi_params.interp_space__ExtrapolationMethod;
            
            app.interp_time__on.Value = fi_params.interp_time__on;
            app.interp_time__Method.Items = { 'linear', 'nearest', 'next', 'previous', 'spline', 'pchip', 'cubic', 'makima' };
            app.interp_time__Method.Value = fi_params.interp_time__Method;
            app.interp_time__max_gap_length.Value = fi_params.interp_time__max_gap_length;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
            app.fi_params_backup = app.parent_app.m.p.fi;
            app.Load_values();
        end

        % Button pushed function: Button_ok
        function Button_okPushed(app, event)
            fi_params = app.parent_app.m.p.fi; 
            
            fi_params.local_filtering__on = app.local_filtering__on.Value;
            fi_params.CC_ratio_limits(1) = app.CC_ratio_limits1.Value; 
            fi_params.CC_ratio_limits(2) = app.CC_ratio_limits2.Value;
            fi_params.CC_maxRaitio_max = app.CC_maxRaitio_max.Value;
            fi_params.mean_im1_limits(1) = app.mean_im1_limits1.Value;
            fi_params.mean_im1_limits(2) = app.mean_im1_limits2.Value;
            fi_params.xd_limits(1) = app.xd_limits1.Value;
            fi_params.xd_limits(2) = app.xd_limits2.Value;
            fi_params.yd_limits(1) = app.yd_limits1.Value;
            fi_params.yd_limits(2) = app.yd_limits2.Value;
            fi_params.d_limits(1) = app.d_limits1.Value;
            fi_params.d_limits(2) = app.d_limits2.Value;
            
            %медианная фильтрация:
            fi_params.median_space__on = app.median_space__on.Value;
            fi_params.median_space__window_size(1) = app.median_space__window_size1.Value;
            fi_params.median_space__window_size(2) = app.median_space__window_size2.Value;
            fi_params.median_space__max_d_difference = app.median_space__max_d_difference.Value;
            
            %медианная фильтрация по времени:
            fi_params.median_time__on = app.median_time__on.Value;
            fi_params.median_time__max_d_difference = app.median_time__max_d_difference.Value;
            fi_params.median_time__dynamic__on = app.median_time__dynamic__on.Value;
            fi_params.median_time__dynamic__window_size = app.median_time__dynamic__window_size.Value;
            
            
             %% Интерполяция:
            fi_params.interp_space__on = app.interp_space__on.Value;
            fi_params.interp_space__Method = app.interp_space__Method.Value;
            fi_params.interp_space__ExtrapolationMethod = app.interp_space__ExtrapolationMethod.Value;
            
            fi_params.interp_time__on = app.interp_time__on.Value;
            fi_params.interp_time__Method = app.interp_time__Method.Value;
            fi_params.interp_time__max_gap_length = app.interp_time__max_gap_length.Value;
            
            app.parent_app.m.Set_fi_params(fi_params);
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(app, event)
            app.parent_app.m.Set_fi_params(app.fi_params_backup);
            delete(app);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            fi_params = Filter_and_interpolation_params();
            app.parent_app.m.Set_fi_params(fi_params);
            app.Load_values();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Параметры фильтрации и интерполяции';
            app.Button_ok = uibutton(app.UIFigure, 'push');
            app.Button_ok.ButtonPushedFcn = createCallbackFcn(app, @Button_okPushed, true);
            app.Button_ok.Text = 'Применить';
            app.Button_cancel = uibutton(app.UIFigure, 'push');
            app.Button_cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_cancelPushed, true);
            app.Button_cancel.Text = 'Отмена';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Сбросить';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Пределы соотношения максимумов';
            app.CC_ratio_limits1 = uieditfield(app.UIFigure, 'numeric');
            app.CC_ratio_limits2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Максимальное значение максимума ККФ';
            app.CC_maxRaitio_max = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Пределы средней по окну яркости изображений';
            app.mean_im1_limits1 = uieditfield(app.UIFigure, 'numeric');
            app.mean_im1_limits2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'Пределы смещения по X';
            app.xd_limits1 = uieditfield(app.UIFigure, 'numeric');
            app.xd_limits2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Пределы смещения по Y';
            app.yd_limits1 = uieditfield(app.UIFigure, 'numeric');
            app.yd_limits2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11Label = uilabel(app.UIFigure);
            app.EditField_11Label.Text = 'Пределы модуля смещения';
            app.d_limits1 = uieditfield(app.UIFigure, 'numeric');
            app.d_limits2 = uieditfield(app.UIFigure, 'numeric');
            app.median_space__on = uicheckbox(app.UIFigure);
            app.median_space__on.Text = 'Медианная фильтрация по пространству';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Размер окна';
            app.median_space__window_size1 = uispinner(app.UIFigure);
            app.median_space__window_size1.Limits = [1 Inf];
            app.median_space__window_size1.Value = 1;
            app.median_space__window_size2 = uispinner(app.UIFigure);
            app.median_space__window_size2.Limits = [1 Inf];
            app.median_space__window_size2.Value = 1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Максимальное отличие смещения от медианного';
            app.median_space__max_d_difference = uieditfield(app.UIFigure, 'numeric');
            app.interp_space__on = uicheckbox(app.UIFigure);
            app.interp_space__on.Text = 'Интерполяция по пространству';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'Способ интерполяции';
            app.interp_space__Method = uidropdown(app.UIFigure);
            app.interp_space__Method.Items = {};
            app.interp_space__Method.Value = {};
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Способ экстраполяции';
            app.interp_space__ExtrapolationMethod = uidropdown(app.UIFigure);
            app.interp_space__ExtrapolationMethod.Items = {};
            app.interp_space__ExtrapolationMethod.Value = {};
            app.median_time__on = uicheckbox(app.UIFigure);
            app.median_time__on.Text = 'Медианная фильтрация по времени';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'Размер окна при динамической фильтрации';
            app.median_time__dynamic__window_size = uispinner(app.UIFigure);
            app.median_time__dynamic__window_size.Limits = [1 Inf];
            app.median_time__dynamic__window_size.Value = 1;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'Максимальное отличие смещения от медианного';
            app.median_time__max_d_difference = uieditfield(app.UIFigure, 'numeric');
            app.local_filtering__on = uicheckbox(app.UIFigure);
            app.local_filtering__on.Text = 'Локальная фильтрация';
            app.median_time__dynamic__on = uicheckbox(app.UIFigure);
            app.median_time__dynamic__on.Text = 'Динамическая фильтрация';
            app.interp_time__on = uicheckbox(app.UIFigure);
            app.interp_time__on.Text = 'Интерполяция по времени';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = 'Способ интерполяции';
            app.interp_time__Method = uidropdown(app.UIFigure);
            app.interp_time__Method.Items = {};
            app.interp_time__Method.Value = {};
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = 'Максимальная длина промежутка';
            app.interp_time__max_gap_length = uispinner(app.UIFigure);
            app.interp_time__max_gap_length.Limits = [1 Inf];
            app.interp_time__max_gap_length.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_fi_params(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end