classdef PIV_db_ipp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CheckBox_bg_on                 matlab.ui.control.CheckBox
        CheckBox_mask_on               matlab.ui.control.CheckBox
        CheckBox_levels_on             matlab.ui.control.CheckBox
        Button_Apply                   matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        color_channel                  matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        Spinner_levels_min             matlab.ui.control.Spinner
        Spinner_2Label                 matlab.ui.control.Label
        Spinner_levels_max             matlab.ui.control.Spinner
        Button_cancel                  matlab.ui.control.Button
        UIAxes_bg                      matlab.ui.control.UIAxes
        UIAxes_mask                    matlab.ui.control.UIAxes
        ButtonReset                    matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        Button_bg_calc                 matlab.ui.control.Button
        EditField_2Label               matlab.ui.control.Label
        EditField_bg_calc_prctile      matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        EditField_bg_calc_N            matlab.ui.control.NumericEditField
        Panel_2                        matlab.ui.container.Panel
        Button_bg_path                 matlab.ui.control.Button
        Button_bg_load                 matlab.ui.control.Button
        EditFieldLabel                 matlab.ui.control.Label
        EditField_bg_path              matlab.ui.control.EditField
        Panel_3                        matlab.ui.container.Panel
        Button_mask_path               matlab.ui.control.Button
        EditField_4Label               matlab.ui.control.Label
        EditField_mask_path            matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        EditField_mask_source_color1   matlab.ui.control.NumericEditField
        EditField_mask_source_color2   matlab.ui.control.NumericEditField
        Button_mask_load               matlab.ui.control.Button
        EditField_mask_source_color3   matlab.ui.control.NumericEditField
        Button_mask_source_color_pick  matlab.ui.control.Button
        Label_5                        matlab.ui.control.Label
        EditField_mask_pick_values_interval_3  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        parent_app
        p_backup Image_preprocessor_params
    end
    
    methods (Access = private)
        
        function Redraw_bg_and_mask(app)
            cla(app.UIAxes_bg);
            cla(app.UIAxes_mask);
            app.UIAxes_bg.XTick = [];
            app.UIAxes_bg.YTick = [];
            app.UIAxes_mask.XTick = [];
            app.UIAxes_mask.YTick = [];
            if ~isempty(app.parent_app.m.p.ipp.bg)
                imagesc(app.parent_app.m.p.ipp.bg,'Parent',app.UIAxes_bg);
                axis(app.UIAxes_bg, 'image');
            end
            if ~isempty(app.parent_app.m.p.ipp.mask)
                imagesc(app.parent_app.m.p.ipp.mask,'Parent',app.UIAxes_mask);
                axis(app.UIAxes_mask, 'image');
%                 colormap(app.UIAxes_mask, 'grey');
            end
        end

        function Update_p(app)
            p = app.parent_app.m.p.ipp;
            p.color_channel                     = app.color_channel.Value;
            p.bg_on                             = app.CheckBox_bg_on.Value;
            p.bg_source_file_path               = app.EditField_bg_path.Value;
            p.bg_source_auto_prctile            = app.EditField_bg_calc_prctile.Value;
            p.bg_source_auto_N                  = app.EditField_bg_calc_N.Value;
            p.mask_on                           = app.CheckBox_mask_on.Value;
            p.mask_set_value                    = app.EditField_mask_pick_values_interval_3.Value;            
            p.mask_source_path                  = app.EditField_mask_path.Value;
            p.mask_source_color(1)              = app.EditField_mask_source_color1.Value;
            p.mask_source_color(2)              = app.EditField_mask_source_color2.Value;
            p.mask_source_color(3)              = app.EditField_mask_source_color3.Value;            
            p.levels_on                         = app.CheckBox_levels_on.Value;
            p.levels_limits(1)                  = app.Spinner_levels_min.Value;
            p.levels_limits(2)                  = app.Spinner_levels_max.Value;            
            app.parent_app.m.Set_ipp_params(p);
            app.Load_values_from_parent_app();
            app.parent_app.m.Redraw_fields();
        end
        
        function Load_values_from_parent_app(app)
            p = app.parent_app.m.p.ipp;
            app.p_backup = p; %сохраняем на случай отмены
            
            app.color_channel.Value             = p.color_channel;
            app.CheckBox_bg_on.Value            = p.bg_on;
            app.EditField_bg_path.Value         = p.bg_source_file_path;
            app.EditField_bg_calc_prctile.Value = p.bg_source_auto_prctile;
            app.EditField_bg_calc_N.Value       = p.bg_source_auto_N;
            app.CheckBox_mask_on.Value                          = p.mask_on;
            app.EditField_mask_pick_values_interval_3.Value     = p.mask_set_value;
            app.EditField_mask_path.Value                       = p.mask_source_path;
            app.EditField_mask_source_color1.Value              = p.mask_source_color(1);
            app.EditField_mask_source_color2.Value              = p.mask_source_color(2);
            app.EditField_mask_source_color3.Value              = p.mask_source_color(3);            
            app.CheckBox_levels_on.Value        = p.levels_on;
            app.Spinner_levels_min.Value        = p.levels_limits(1);
            app.Spinner_levels_max.Value        = p.levels_limits(2);
            
            app.Redraw_bg_and_mask();
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;            
            app.Load_values_from_parent_app();  
        end

        % Button pushed function: Button_bg_load
        function Button_bg_loadPushed(app, event)
            if ~isempty(app.EditField_bg_path.Value)
                app.parent_app.m.Load_and_use_bg(app.EditField_bg_path.Value);
                app.Load_values_from_parent_app();                  
            end
        end

        % Button pushed function: Button_mask_load
        function Button_mask_loadPushed(app, event)
            if ~isempty(app.EditField_mask_path.Value)
                app.parent_app.m.Load_and_use_mask(...
                    app.EditField_mask_path.Value,...
                    [app.EditField_mask_source_color1.Value,...
                     app.EditField_mask_source_color2.Value,...
                     app.EditField_mask_source_color3.Value]);
                app.Load_values_from_parent_app();       
            end
        end

        % Button pushed function: Button_bg_path
        function Button_bg_pathPushed(app, event)
            [file,path] = uigetfile('*');
            if file ~= 0
                selpath = fullfile(path,file);
                app.EditField_bg_path.Value = selpath;
                app.parent_app.m.Load_and_use_bg(app.EditField_bg_path.Value);
                app.Load_values_from_parent_app();     
            end
        end

        % Button pushed function: Button_mask_path
        function Button_mask_pathPushed(app, event)
            [file,path] = uigetfile('*');
            if file ~= 0
                selpath = fullfile(path,file);
                app.EditField_mask_path.Value = selpath;
            end
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(app, event)
            app.parent_app.m.Set_ipp_params( app.p_backup );
            delete(app);
        end

        % Button pushed function: Button_Apply
        function Button_ApplyPushed(app, event)
            app.Update_p();
        end

        % Button pushed function: Button_bg_calc
        function Button_bg_calcPushed(app, event)
            app.parent_app.m.Calc_and_use_bg( app.EditField_bg_calc_N.Value, app.EditField_bg_calc_prctile.Value );            
            app.parent_app.m.Redraw_fields();
            app.Load_values_from_parent_app();     
        end

        % Button pushed function: ButtonReset
        function ButtonResetButtonPushed(app, event)
            app.parent_app.m.Set_ipp_params(Image_preprocessor_params());            
            app.parent_app.m.Redraw_fields();
            app.Load_values_from_parent_app();
        end

        % Button pushed function: Button_mask_source_color_pick
        function Button_mask_source_color_pickPushed(app, event)
            im_path = app.EditField_mask_path.Value;
            if ~isempty(im_path)
                f = figure('name','Выберите мышкой пиксель, принадлежащий маске');
                im = imread(im_path);
                imshow(im);
                p = round(ginput(1));
                close(f);
                c = double(squeeze(im(p(2),p(1),:)));                
                app.EditField_mask_source_color1.Value = c(1);
                app.EditField_mask_source_color2.Value = c(2);
                app.EditField_mask_source_color3.Value = c(3);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Параметры изображений';
            app.CheckBox_bg_on = uicheckbox(app.UIFigure);
            app.CheckBox_bg_on.Text = 'Вычитать фон';
            app.CheckBox_mask_on = uicheckbox(app.UIFigure);
            app.CheckBox_mask_on.Text = 'Использовать маску';
            app.CheckBox_levels_on = uicheckbox(app.UIFigure);
            app.CheckBox_levels_on.Text = 'Использовать уровни';
            app.Button_Apply = uibutton(app.UIFigure, 'push');
            app.Button_Apply.ButtonPushedFcn = createCallbackFcn(app, @Button_ApplyPushed, true);
            app.Button_Apply.Text = 'Применить';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Канал';
            app.color_channel = uispinner(app.UIFigure);
            app.color_channel.Limits = [1 3];
            app.color_channel.Value = 1;
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Минимум';
            app.Spinner_levels_min = uispinner(app.UIFigure);
            app.Spinner_2Label = uilabel(app.UIFigure);
            app.Spinner_2Label.Text = 'Максимум';
            app.Spinner_levels_max = uispinner(app.UIFigure);
            app.Button_cancel = uibutton(app.UIFigure, 'push');
            app.Button_cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_cancelPushed, true);
            app.Button_cancel.Text = 'Отмена';
            app.UIAxes_bg = uiaxes(app.UIFigure);
            title(app.UIAxes_bg, 'Фон')
            app.UIAxes_mask = uiaxes(app.UIFigure);
            title(app.UIAxes_mask, 'Маска')
            app.ButtonReset = uibutton(app.UIFigure, 'push');
            app.ButtonReset.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetButtonPushed, true);
            app.ButtonReset.Text = 'Сбросить';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Вычисление фона';
            app.Button_bg_calc = uibutton(app.Panel, 'push');
            app.Button_bg_calc.ButtonPushedFcn = createCallbackFcn(app, @Button_bg_calcPushed, true);
            app.Button_bg_calc.Text = 'Вычислить фон';
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = 'Перцентиль';
            app.EditField_bg_calc_prctile = uieditfield(app.Panel, 'numeric');
            app.EditField_bg_calc_prctile.Limits = [0 100];
            app.EditField_bg_calc_prctile.ValueDisplayFormat = '%.0f';
            app.EditField_bg_calc_prctile.Value = 5;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'Число кадров';
            app.EditField_bg_calc_N = uieditfield(app.Panel, 'numeric');
            app.EditField_bg_calc_N.Limits = [1 Inf];
            app.EditField_bg_calc_N.ValueDisplayFormat = '%.0f';
            app.EditField_bg_calc_N.Value = 100;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Загрузить фон';
            app.Button_bg_path = uibutton(app.Panel_2, 'push');
            app.Button_bg_path.ButtonPushedFcn = createCallbackFcn(app, @Button_bg_pathPushed, true);
            app.Button_bg_path.Text = 'Указать';
            app.Button_bg_load = uibutton(app.Panel_2, 'push');
            app.Button_bg_load.ButtonPushedFcn = createCallbackFcn(app, @Button_bg_loadPushed, true);
            app.Button_bg_load.Text = 'Загрузить фон из файла';
            app.EditFieldLabel = uilabel(app.Panel_2);
            app.EditFieldLabel.Text = 'Путь к файлу';
            app.EditField_bg_path = uieditfield(app.Panel_2, 'text');
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = 'Загрузить маску';
            app.Button_mask_path = uibutton(app.Panel_3, 'push');
            app.Button_mask_path.ButtonPushedFcn = createCallbackFcn(app, @Button_mask_pathPushed, true);
            app.Button_mask_path.Text = 'Указать';
            app.EditField_4Label = uilabel(app.Panel_3);
            app.EditField_4Label.Text = 'Путь к файлу';
            app.EditField_mask_path = uieditfield(app.Panel_3, 'text');
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = {'Цвет маски в файле:'; ''};
            app.EditField_mask_source_color1 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_mask_source_color1.Limits = [0 Inf];
            app.EditField_mask_source_color1.ValueDisplayFormat = '%.0f';
            app.EditField_mask_source_color1.Value = 250;
            app.EditField_mask_source_color2 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_mask_source_color2.Limits = [0 Inf];
            app.EditField_mask_source_color2.ValueDisplayFormat = '%.0f';
            app.EditField_mask_source_color2.Value = 255;
            app.Button_mask_load = uibutton(app.Panel_3, 'push');
            app.Button_mask_load.ButtonPushedFcn = createCallbackFcn(app, @Button_mask_loadPushed, true);
            app.Button_mask_load.Text = 'Загрузить маску из файла';
            app.EditField_mask_source_color3 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_mask_source_color3.Limits = [0 Inf];
            app.EditField_mask_source_color3.ValueDisplayFormat = '%.0f';
            app.EditField_mask_source_color3.Value = 255;
            app.Button_mask_source_color_pick = uibutton(app.Panel_3, 'push');
            app.Button_mask_source_color_pick.ButtonPushedFcn = createCallbackFcn(app, @Button_mask_source_color_pickPushed, true);
            app.Button_mask_source_color_pick.Text = {'Указать'; ''};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Заменять замаскированные пиксели на';
            app.EditField_mask_pick_values_interval_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_mask_pick_values_interval_3.Limits = [0 Inf];
            app.EditField_mask_pick_values_interval_3.ValueDisplayFormat = '%.0f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_ipp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end