classdef PIV_db_piv_frames < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        pf_apply        matlab.ui.control.Button
        pf_cancel       matlab.ui.control.Button
        Label           matlab.ui.control.Label
        pf_frame_start  matlab.ui.control.NumericEditField
        Label_2         matlab.ui.control.Label
        pf_frame_end    matlab.ui.control.NumericEditField
        NLabel          matlab.ui.control.Label
        pf_frame_skip   matlab.ui.control.Spinner
        NLabel_2        matlab.ui.control.Label
        pf_frame_step   matlab.ui.control.Spinner
        pf_select_all   matlab.ui.control.Button
        pf_UIAxes       matlab.ui.control.UIAxes
        pf_Label_info   matlab.ui.control.Label
        pf_Reset        matlab.ui.control.Button
    end

    
    properties (Access = private)
        m PIV_main
        parent_app
        pf_backup PIV_frames
    end
    
    methods (Access = private)
        function Update_pf(app)
            frame_start    = app.pf_frame_start.Value;
            frame_end      = app.pf_frame_end.Value;
            frame_skip     = app.pf_frame_skip.Value;
            frame_step     = app.pf_frame_step.Value;
            try
            pf = PIV_frames(frame_start, frame_end, frame_skip, frame_step);            
            catch
                errordlg('Некорректные параметры: ни одного кадра не выбрано','Ошибка');
                return;
            end
            if isempty(pf.first_frames)
                errordlg('Некорректные параметры: ни одного кадра не выбрано','Ошибка');
                return;
            end
            app.parent_app.m.Set_pf(pf);
            app.parent_app.m.Redraw_fields();
            app.parent_app.init_gui(); %чтобы обновились пределы для спинера поле
            
            
            %построения
            cla(app.pf_UIAxes);
            app.pf_UIAxes.YTick = [];
            app.pf_UIAxes.XLim = [1 app.parent_app.m.im_count];
            app.pf_UIAxes.YLim = [-1 2];            
            z = 0 * pf.first_frames;
            quiver(app.pf_UIAxes, ...
                pf.first_frames,...
                z,...
                z + pf.frame_step, ...
                z + 1,...
                0, 'k',...
                'ShowArrowHead','off');
            app.pf_Label_info.Text = sprintf('Всего пар кадров: %d',pf.frame_count);
        end
        
        function Load_values(app)
            pf = app.parent_app.m.p.pf;            
            app.pf_frame_start.Value = pf.frame_start;
            app.pf_frame_end.Value   = pf.frame_end;
            app.pf_frame_skip.Value  = pf.frame_skip;
            app.pf_frame_step.Value  = pf.frame_step;
        end        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
            app.pf_backup = app.parent_app.m.p.pf;
            app.Load_values();            
            app.Update_pf();
        end

        % Button pushed function: pf_apply
        function pf_applyPushed(app, event)
            app.Update_pf();
        end

        % Button pushed function: pf_cancel
        function pf_cancelPushed(app, event)
            app.parent_app.m.Set_pf(app.pf_backup);
            app.parent_app.init_gui(); %чтобы обновились пределы для спинера поле            
            delete(app);
        end

        % Button pushed function: pf_select_all
        function pf_select_allPushed(app, event)
            app.pf_frame_start.Value = 1;
            app.pf_frame_end.Value = app.parent_app.m.im_count;
        end

        % Button pushed function: pf_Reset
        function pf_ResetButtonPushed(app, event)
            pf_frame_start    = 1;
            pf_frame_end      = app.parent_app.m.im_count;
            pf_frame_skip     = 1;
            pf_frame_step     = 1;
            pf = PIV_frames(pf_frame_start, pf_frame_end, pf_frame_skip, pf_frame_step);
            app.parent_app.m.Set_pf(pf);
            app.parent_app.init_gui(); %чтобы обновились пределы для спинера поле                        
            app.Load_values();
            app.Update_pf();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Параметры кадров';
            app.pf_apply = uibutton(app.UIFigure, 'push');
            app.pf_apply.ButtonPushedFcn = createCallbackFcn(app, @pf_applyPushed, true);
            app.pf_apply.Text = 'Применить';
            app.pf_cancel = uibutton(app.UIFigure, 'push');
            app.pf_cancel.ButtonPushedFcn = createCallbackFcn(app, @pf_cancelPushed, true);
            app.pf_cancel.Text = 'Отмена';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Начинать с кадра';
            app.pf_frame_start = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Заканчивать на кадре';
            app.pf_frame_end = uieditfield(app.UIFigure, 'numeric');
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'Обрабатывать каждый N-й кадр';
            app.pf_frame_skip = uispinner(app.UIFigure);
            app.NLabel_2 = uilabel(app.UIFigure);
            app.NLabel_2.Text = 'Сравнивать через N кадров';
            app.pf_frame_step = uispinner(app.UIFigure);
            app.pf_select_all = uibutton(app.UIFigure, 'push');
            app.pf_select_all.ButtonPushedFcn = createCallbackFcn(app, @pf_select_allPushed, true);
            app.pf_select_all.Text = 'Вся запись';
            app.pf_UIAxes = uiaxes(app.UIFigure);
            title(app.pf_UIAxes, 'Выбранные кадры')
            xlabel(app.pf_UIAxes, 'Номер кадра')
            app.pf_Label_info = uilabel(app.UIFigure);
            app.pf_Label_info.Text = 'Инфо';
            app.pf_Reset = uibutton(app.UIFigure, 'push');
            app.pf_Reset.ButtonPushedFcn = createCallbackFcn(app, @pf_ResetButtonPushed, true);
            app.pf_Reset.Text = 'Сбросить';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_piv_frames(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end