classdef PIV_db_piv_grid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        XLabel_4             matlab.ui.control.Label
        YLabel_4             matlab.ui.control.Label
        Button_lims_auto     matlab.ui.control.Button
        Button_cancel        matlab.ui.control.Button
        Button_ginput_start  matlab.ui.control.Button
        Spinner_mask_prc     matlab.ui.control.Spinner
        cCheckBox_mask       matlab.ui.control.CheckBox
        Reset                matlab.ui.control.Button
        Button_overlap       matlab.ui.control.Button
        Spinner              matlab.ui.control.Spinner
        Label_2              matlab.ui.control.Label
        Spinner_step_x       matlab.ui.control.Spinner
        Label_5              matlab.ui.control.Label
        Spinner_step_y       matlab.ui.control.Spinner
        Label                matlab.ui.control.Label
        Spinner_start_x      matlab.ui.control.Spinner
        Label_4              matlab.ui.control.Label
        Spinner_start_y      matlab.ui.control.Spinner
        Label_3              matlab.ui.control.Label
        Spinner_end_x        matlab.ui.control.Spinner
        Label_6              matlab.ui.control.Label
        Spinner_end_y        matlab.ui.control.Spinner
        Label_overlap        matlab.ui.control.Label
        Button_apply_lims    matlab.ui.control.Button
    end

    
    properties (Access = private)     
        parent_app        
        pg_backup PIV_grid
    end

    methods (Access = private)
    
        function Load_values_from_parent_app(app)
            pg = app.parent_app.m.p.pg;
            app.Spinner_start_x.Value = pg.xArr(1);
            app.Spinner_start_y.Value = pg.yArr(1);            
            app.Spinner_end_x.Value   = pg.xArr(end);
            app.Spinner_end_y.Value   = pg.yArr(end);            
            if ~isnan(pg.xStep)
                app.Spinner_step_x.Value  = pg.xStep;
            end
            if ~isnan(pg.yStep)
                app.Spinner_step_y.Value  = pg.yStep;
            end        
            overlap_xy = PIV_grid.Get_overlap(...
                app.parent_app.m.p.pp.wSize,...
                [pg.xStep, pg.yStep]);
            app.Label_overlap.Text = sprintf(...
                'Текущее перекрытие: %.0f%% по x, %.0f%% по y',...
                overlap_xy(1), overlap_xy(2));
        end
        
        
        function Update_m(app)
            s_x  = app.Spinner_start_x.Value;
            s_y  = app.Spinner_start_y.Value;
            st_x = app.Spinner_step_x.Value;
            st_y = app.Spinner_step_y.Value;
            e_x  = app.Spinner_end_x.Value;
            e_y  = app.Spinner_end_y.Value;
            x_arr = s_x:st_x:e_x;
            y_arr = s_y:st_y:e_y;
            if isempty(x_arr) || isempty(y_arr)
                errordlg('Некорректные параметры сетки: нет узлов','Ошибка');
                return;
            end            
            pg = PIV_grid(x_arr,y_arr);
            if app.cCheckBox_mask.Value
                mask = app.parent_app.m.p.ipp.mask;
                if ~isempty(mask)
                    pg = pg.Disable_from_mask(...
                        mask,...
                        app.parent_app.m.p.pp.wSize,...
                        app.Spinner_mask_prc.Value);
                end
            end
            app.parent_app.m.Set_pg(pg);     
            app.parent_app.m.Redraw_fields();     
            app.Load_values_from_parent_app();
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            %             addpath('D:\Кандауров\Программы\Matlab\_path');
            app.parent_app = parent_app;            
            app.pg_backup = app.parent_app.m.p.pg;
      
            app.Load_values_from_parent_app();
        end

        % Callback function
        function Button_apply_evalPushed(app, event)
            t_x = app.evalXEditField.Value;
            t_y = app.evalYEditField.Value;
            im_size_x = app.parent_app.m.im_size(1);
            im_size_y = app.parent_app.m.im_size(2);
            w_size_x = app.parent_app.m.p.pp.wSize(1);
            w_size_y = app.parent_app.m.p.pp.wSize(2);
            eval (['x_arr = ' t_x]);
            eval (['y_arr = ' t_y]);
            app.parent_app.m.Add_to_log(sprintf('x %d ',x_arr));
            app.parent_app.m.Add_to_log(sprintf('y %d ',y_arr));
            pg = PIV_grid(x_arr,y_arr);
            app.parent_app.m.Set_pg(pg);
        end

        % Button pushed function: Button_lims_auto
        function Button_lims_autoPushed(app, event)
            im_size = app.parent_app.m.im_size;
            step_x_y = [app.Spinner_step_x.Value, app.Spinner_step_y.Value];
            pg = PIV_grid.Get_max_grid(im_size, abs(step_x_y));   
            app.parent_app.m.Set_pg(pg);
            app.Load_values_from_parent_app();
        end

        % Button pushed function: Button_apply_lims
        function Button_apply_limsPushed(app, event)
            app.Update_m();
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(app, event)
            app.parent_app.m.Set_pg(app.pg_backup);
            delete(app);
        end

        % Button pushed function: Button_ginput_start
        function Button_ginput_startPushed(app, event)
            p = app.parent_app.m.selected_points;
            if size(p,2) < 2, return; end
            p1 = p(end-1, :);
            p2 = p(end  , :);
            sx = min( p1(1), p2(1) );
            sy = min( p1(2), p2(2) );
            ex = max( p1(1), p2(1) );
            ey = max( p1(2), p2(2) );
            app.Spinner_start_x.Value = round(sx);
            app.Spinner_start_y.Value = round(sy);
            app.Spinner_end_x.Value   = round(ex);
            app.Spinner_end_y.Value   = round(ey);
            app.Update_m();
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            im_size = app.parent_app.m.im_size;
            step_x_y = round(app.parent_app.m.p.pp.wSize/2);
            pg = PIV_grid.Get_max_grid(im_size, abs(step_x_y));   
            app.parent_app.m.Set_pg(pg);
            app.Load_values_from_parent_app();
        end

        % Callback function
        function Reset_2ButtonPushed(app, event)
            app.evalXEditField.Value = 'ceil( w_size_x/2 + 1 ) : w_size_x/2 : floor( im_size_x - w_size_x/2 + 1 );';
            app.evalYEditField.Value = 'ceil( w_size_y/2 + 1 ) : w_size_y/2 : floor( im_size_y - w_size_y/2 + 1 );';                                    
        end

        % Button pushed function: Button_overlap
        function Button_overlapPushed(app, event)
            step_xy = round (  PIV_grid.Get_step_from_overlap(...
                app.parent_app.m.p.pp.wSize,...
                app.Spinner.Value) );
            if ~isnan(step_xy(1))  && step_xy(1) > 0
                app.Spinner_step_x.Value = step_xy(1);
            end
            if ~isnan(step_xy(2))  && step_xy(2) > 0
                app.Spinner_step_y.Value = step_xy(2);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Параметры сетки';
            app.XLabel_4 = uilabel(app.UIFigure);
            app.XLabel_4.Text = 'Координаты узлов по X:';
            app.YLabel_4 = uilabel(app.UIFigure);
            app.YLabel_4.Text = 'Координаты узлов по Y:';
            app.Button_lims_auto = uibutton(app.UIFigure, 'push');
            app.Button_lims_auto.ButtonPushedFcn = createCallbackFcn(app, @Button_lims_autoPushed, true);
            app.Button_lims_auto.Text = 'Выбрать пределы автоматически';
            app.Button_cancel = uibutton(app.UIFigure, 'push');
            app.Button_cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_cancelPushed, true);
            app.Button_cancel.Text = 'Отмена';
            app.Button_ginput_start = uibutton(app.UIFigure, 'push');
            app.Button_ginput_start.ButtonPushedFcn = createCallbackFcn(app, @Button_ginput_startPushed, true);
            app.Button_ginput_start.Text = {'Использовать две последние выбранные'; 'точки как углы области'};
            app.Spinner_mask_prc = uispinner(app.UIFigure);
            app.Spinner_mask_prc.Limits = [0 100];
            app.Spinner_mask_prc.Value = 50;
            app.cCheckBox_mask = uicheckbox(app.UIFigure);
            app.cCheckBox_mask.Text = 'Убрать узлы сетки c % пикселей маски в окне больше';
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Сбросить';
            app.Button_overlap = uibutton(app.UIFigure, 'push');
            app.Button_overlap.ButtonPushedFcn = createCallbackFcn(app, @Button_overlapPushed, true);
            app.Button_overlap.Text = 'Использовать перекрытие (%):';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Value = 50;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'шаг';
            app.Spinner_step_x = uispinner(app.UIFigure);
            app.Spinner_step_x.Value = 32;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'шаг';
            app.Spinner_step_y = uispinner(app.UIFigure);
            app.Spinner_step_y.Value = 32;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'от';
            app.Spinner_start_x = uispinner(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'от';
            app.Spinner_start_y = uispinner(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'до';
            app.Spinner_end_x = uispinner(app.UIFigure);
            app.Spinner_end_x.Value = 500;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'до';
            app.Spinner_end_y = uispinner(app.UIFigure);
            app.Spinner_end_y.Value = 500;
            app.Label_overlap = uilabel(app.UIFigure);
            app.Label_overlap.Text = 'Текущее перекрытие:';
            app.Button_apply_lims = uibutton(app.UIFigure, 'push');
            app.Button_apply_lims.ButtonPushedFcn = createCallbackFcn(app, @Button_apply_limsPushed, true);
            app.Button_apply_lims.Text = 'Применить';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_piv_grid(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end