classdef PIV_db_piv_params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIVUIFigure            matlab.ui.Figure
        Button_ok              matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        EditField_wSize_x      matlab.ui.control.NumericEditField
        EditField_wSize_y      matlab.ui.control.NumericEditField
        Button_cancel          matlab.ui.control.Button
        Label_2                matlab.ui.control.Label
        EditField_maxDispl_x   matlab.ui.control.NumericEditField
        EditField_maxDispl_y   matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        EditField_minS2NRatio  matlab.ui.control.NumericEditField
        CheckBox_useSubpixel   matlab.ui.control.CheckBox
        CheckBox_doMultiMax    matlab.ui.control.CheckBox
        CheckBox_saveCCandIMC  matlab.ui.control.CheckBox
        Reset                  matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        wSize1x                matlab.ui.control.NumericEditField
        wSize1y                matlab.ui.control.NumericEditField
        doFirstPass            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        parent_app
        pp_backup PIV_params
    end
    
    methods (Access = private)
        function update_pp(app)
            pp = app.parent_app.m.p.pp;
            
            pp.doFirstPass    = app.doFirstPass.Value;
            pp.wSize1(1)      = app.wSize1x.Value;
            pp.wSize1(2)      = app.wSize1y.Value;
            pp.wSize(1)       = app.EditField_wSize_x.Value;
            pp.wSize(2)       = app.EditField_wSize_y.Value;
            pp.maxDispl(1)    = app.EditField_maxDispl_x.Value;
            pp.maxDispl(2)    = app.EditField_maxDispl_y.Value;
            pp.minS2NRatio    = app.EditField_minS2NRatio.Value;
            pp.useSubpixel    = app.CheckBox_useSubpixel.Value;
            pp.doMultiMax     = app.CheckBox_doMultiMax.Value;
            pp.saveCCandIMC   = app.CheckBox_saveCCandIMC.Value;
            
            app.parent_app.m.Set_pp(pp);
        end
        
        function Load_values(app)
            pp = app.parent_app.m.p.pp;
            
            app.doFirstPass.Value           = pp.doFirstPass;
            app.wSize1x.Value               = pp.wSize1(1);
            app.wSize1y.Value               = pp.wSize1(2);
            app.EditField_wSize_x.Value     = pp.wSize(1);
            app.EditField_wSize_y.Value     = pp.wSize(2);
            app.EditField_maxDispl_x.Value  = pp.maxDispl(1);
            app.EditField_maxDispl_y.Value  = pp.maxDispl(2);
            app.EditField_minS2NRatio.Value = pp.minS2NRatio;
            app.CheckBox_useSubpixel.Value  = pp.useSubpixel;
            app.CheckBox_doMultiMax.Value   = pp.doMultiMax;
            app.CheckBox_saveCCandIMC.Value = pp.saveCCandIMC;
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
            app.pp_backup = app.parent_app.m.p.pp;
            app.Load_values();
        end

        % Button pushed function: Button_ok
        function Button_okPushed(app, event)
            app.update_pp();
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(app, event)
            app.parent_app.m.Set_pp(app.pp_backup);
            delete(app);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            pp = PIV_params();
            app.parent_app.m.Set_pp(pp);
            app.Load_values();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIVUIFigure = uifigure;
            app.PIVUIFigure.Name = 'Параметры PIV';
            app.Button_ok = uibutton(app.PIVUIFigure, 'push');
            app.Button_ok.ButtonPushedFcn = createCallbackFcn(app, @Button_okPushed, true);
            app.Button_ok.Text = 'Применить';
            app.Label = uilabel(app.PIVUIFigure);
            app.Label.Text = 'Размер окна';
            app.EditField_wSize_x = uieditfield(app.PIVUIFigure, 'numeric');
            app.EditField_wSize_x.Limits = [1 Inf];
            app.EditField_wSize_x.Value = 1;
            app.EditField_wSize_y = uieditfield(app.PIVUIFigure, 'numeric');
            app.EditField_wSize_y.Limits = [1 Inf];
            app.EditField_wSize_y.Value = 1;
            app.Button_cancel = uibutton(app.PIVUIFigure, 'push');
            app.Button_cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_cancelPushed, true);
            app.Button_cancel.Text = 'Отмена';
            app.Label_2 = uilabel(app.PIVUIFigure);
            app.Label_2.Text = 'Максимальное смещение';
            app.EditField_maxDispl_x = uieditfield(app.PIVUIFigure, 'numeric');
            app.EditField_maxDispl_y = uieditfield(app.PIVUIFigure, 'numeric');
            app.Label_3 = uilabel(app.PIVUIFigure);
            app.Label_3.Text = 'Минимальное соотношение сигнал шум';
            app.EditField_minS2NRatio = uieditfield(app.PIVUIFigure, 'numeric');
            app.EditField_minS2NRatio.Value = 1;
            app.CheckBox_useSubpixel = uicheckbox(app.PIVUIFigure);
            app.CheckBox_useSubpixel.Text = 'Субпиксельная точность';
            app.CheckBox_doMultiMax = uicheckbox(app.PIVUIFigure);
            app.CheckBox_doMultiMax.Text = 'Поиск нескольких максимумов';
            app.CheckBox_saveCCandIMC = uicheckbox(app.PIVUIFigure);
            app.CheckBox_saveCCandIMC.Text = 'Сохранять дополнительные данные';
            app.Reset = uibutton(app.PIVUIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Сбросить';
            app.Label_4 = uilabel(app.PIVUIFigure);
            app.Label_4.Text = 'Размер окна на первом проходе';
            app.wSize1x = uieditfield(app.PIVUIFigure, 'numeric');
            app.wSize1x.Limits = [1 Inf];
            app.wSize1x.Value = 1;
            app.wSize1y = uieditfield(app.PIVUIFigure, 'numeric');
            app.wSize1y.Limits = [1 Inf];
            app.wSize1y.Value = 1;
            app.doFirstPass = uicheckbox(app.PIVUIFigure);
            app.doFirstPass.Text = 'Использовать первый проход';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_piv_params(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIVUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIVUIFigure)
        end
    end
end