classdef PIV_db_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CheckBox_imon                   matlab.ui.control.CheckBox
        CheckBox_gridon                 matlab.ui.control.CheckBox
        CheckBox_quiver_on_piv          matlab.ui.control.CheckBox
        CheckBox_pcoloron               matlab.ui.control.CheckBox
        DropDown_pcolor_value           matlab.ui.control.DropDown
        ButtonGroup_im_select           matlab.ui.container.ButtonGroup
        Button__im_select_initial       matlab.ui.control.RadioButton
        Button__im_select_processed     matlab.ui.control.RadioButton
        CheckBox__quiver_dir_only       matlab.ui.control.CheckBox
        CheckBox_pcolor_caxis_auto      matlab.ui.control.CheckBox
        EditFieldLabel                  matlab.ui.control.Label
        EditField_grid_s                matlab.ui.control.EditField
        Spinner_3Label                  matlab.ui.control.Label
        Spinner_grid_ssize              matlab.ui.control.Spinner
        ColormapEditFieldLabel          matlab.ui.control.Label
        ColormapEditField_im_cm         matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        EditField_q_scale               matlab.ui.control.NumericEditField
        EditField_4Label                matlab.ui.control.Label
        EditField_p_cl_1                matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        EditField_p_cl_2                matlab.ui.control.NumericEditField
        Button_apply                    matlab.ui.control.Button
        Button_cancel                   matlab.ui.control.Button
        EditField_6Label                matlab.ui.control.Label
        EditField_g_c                   matlab.ui.control.EditField
        CheckBox_p_cbon                 matlab.ui.control.CheckBox
        EditField_3Label_2              matlab.ui.control.Label
        EditField_q_c_piv               matlab.ui.control.EditField
        EditField_3Label_3              matlab.ui.control.Label
        EditField_q_c_fi                matlab.ui.control.EditField
        ShadingDropDownLabel            matlab.ui.control.Label
        ShadingDropDown_pcolor_shading  matlab.ui.control.DropDown
        ColormapLabel                   matlab.ui.control.Label
        ShadingDropDown_pcolor_colormap  matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Spinner_quiver_step             matlab.ui.control.Spinner
        Label_2                         matlab.ui.control.Label
        ShadingDropDown_pcolor_source   matlab.ui.control.DropDown
        CheckBox_quiver_on_fi           matlab.ui.control.CheckBox
        grid_show_status                matlab.ui.control.CheckBox
        grid_show_status_filtering      matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
        quiver_on_mean                  matlab.ui.control.CheckBox
        EditField_3Label_4              matlab.ui.control.Label
        quiver_color_mean               matlab.ui.control.EditField
        plot_in_scale                   matlab.ui.control.CheckBox
        show_grid                       matlab.ui.control.CheckBox
        show_ticks                      matlab.ui.control.CheckBox
        show_title                      matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        cb_grid_show_windows            matlab.ui.control.CheckBox
    end


    properties (Access = private)
        parent_app
        
        plot_params_backup Plot_params
    end

    methods (Access = private)
    
        function Load_values(app)
            plot_params = app.parent_app.m.p.plot_params;
            app.CheckBox_imon.Value                 = plot_params.image_on;
            app.Button__im_select_processed.Value   = plot_params.image_processed;
            app.ColormapEditField_im_cm.Value       = plot_params.image_colormap;
            
            app.CheckBox_gridon.Value               = plot_params.grid_on;
            app.EditField_grid_s.Value              = plot_params.grid_symbol;
            app.Spinner_grid_ssize.Value            = plot_params.grid_symbol_size;
            app.EditField_g_c.Value                 = plot_params.grid_symbol_color;
            app.grid_show_status.Value              = plot_params.grid_show_status;
            app.grid_show_status_filtering.Value    = plot_params.grid_show_status_filtering;
            app.cb_grid_show_windows.Value          = plot_params.grid_show_windows;
                                    
            app.CheckBox_pcoloron.Value             = plot_params.pcolor_on;
            app.ShadingDropDown_pcolor_source.Value = plot_params.pcolor_source;
            app.ShadingDropDown_pcolor_colormap.Value = plot_params.pcolor_colormap;
            app.DropDown_pcolor_value.Value         = plot_params.pcolor_value;
            app.CheckBox_pcolor_caxis_auto.Value    = plot_params.pcolor_caxis_auto;
            app.EditField_p_cl_1.Value              = plot_params.pcolor_caxis(1);
            app.EditField_p_cl_2.Value              = plot_params.pcolor_caxis(2);
            app.CheckBox_p_cbon.Value               = plot_params.pcolor_colorbar_on;
            app.ShadingDropDown_pcolor_shading.Value= plot_params.pcolor_shading;
            
            app.CheckBox_quiver_on_piv.Value         = plot_params.quiver_on_piv;
            app.CheckBox_quiver_on_fi.Value         = plot_params.quiver_on_fi;
            app.EditField_q_c_piv.Value              = plot_params.quiver_color_piv;
            app.EditField_q_c_fi.Value              = plot_params.quiver_color_fi;
            app.EditField_q_scale.Value             = plot_params.quiver_scale;
            app.Spinner_quiver_step.Value           = plot_params.quiver_step;
            app.CheckBox__quiver_dir_only.Value     = plot_params.quiver_directions_only;
            
            app.quiver_on_mean.Value                = plot_params.quiver_on_mean;
            app.quiver_color_mean.Value             = plot_params.quiver_color_mean;
            
            app.plot_in_scale.Value                 = plot_params.plot_in_scale;
            app.show_grid.Value                     = plot_params.show_grid;
            app.show_ticks.Value                    = plot_params.show_ticks;
            app.show_title.Value                    = plot_params.show_title;            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
            plot_params = app.parent_app.m.p.plot_params;
            app.plot_params_backup = plot_params;
            
            app.ShadingDropDown_pcolor_colormap.Items = {
                'parula',...
                'jet',...
                'hsv',...
                'hot',...
                'cool',...
                'spring',...
                'summer',...
                'autumn',...
                'winter',...
                'gray',...
                'bone',...
                'copper',...
                'pink',...
                'lines',...
                'colorcube',...
                'prism',...
                'flag',...
                'white'};
            
            app.Load_values();
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(app, event)
            app.parent_app.m.Set_plot_params( app.plot_params_backup );
            delete(app);
        end

        % Button pushed function: Button_apply
        function Button_applyPushed(app, event)
            plot_params = app.parent_app.m.p.plot_params;
            
            plot_params.image_on=  app.CheckBox_imon.Value;
            plot_params.image_processed= app.Button__im_select_processed.Value;
            plot_params.image_colormap=app.ColormapEditField_im_cm.Value;
            
            plot_params.grid_on=app.CheckBox_gridon.Value;
            plot_params.grid_symbol=app.EditField_grid_s.Value;
            plot_params.grid_symbol_size=app.Spinner_grid_ssize.Value;
            plot_params.grid_symbol_color=app.EditField_g_c.Value;
            plot_params.grid_show_status = app.grid_show_status.Value;
            plot_params.grid_show_status_filtering = app.grid_show_status_filtering.Value;
            plot_params.grid_show_windows = app.cb_grid_show_windows.Value;
                                    
            
            plot_params.pcolor_on=app.CheckBox_pcoloron.Value;
            plot_params.pcolor_source = app.ShadingDropDown_pcolor_source.Value;
            plot_params.pcolor_colormap=app.ShadingDropDown_pcolor_colormap.Value;
            plot_params.pcolor_value=app.DropDown_pcolor_value.Value;
            plot_params.pcolor_caxis_auto=app.CheckBox_pcolor_caxis_auto.Value;
            plot_params.pcolor_caxis(1)=app.EditField_p_cl_1.Value;
            plot_params.pcolor_caxis(2)=app.EditField_p_cl_2.Value;
            plot_params.pcolor_colorbar_on=app.CheckBox_p_cbon.Value;
            plot_params.pcolor_shading = app.ShadingDropDown_pcolor_shading.Value;
            
            plot_params.quiver_on_piv     = app.CheckBox_quiver_on_piv.Value;
            plot_params.quiver_on_fi = app.CheckBox_quiver_on_fi.Value;
            plot_params.quiver_color_piv = app.EditField_q_c_piv.Value;
            plot_params.quiver_color_fi = app.EditField_q_c_fi.Value;
            plot_params.quiver_scale = app.EditField_q_scale.Value;
            plot_params.quiver_step = app.Spinner_quiver_step.Value;
            plot_params.quiver_directions_only = app.CheckBox__quiver_dir_only.Value;
            
            plot_params.quiver_on_mean = app.quiver_on_mean.Value;  
            plot_params.quiver_color_mean = app.quiver_color_mean.Value;      
            
            plot_params.plot_in_scale   = app.plot_in_scale.Value;
            plot_params.show_grid       = app.show_grid.Value;
            plot_params.show_ticks      = app.show_ticks.Value;
            plot_params.show_title      = app.show_title.Value;      
            
            app.parent_app.m.Set_plot_params( plot_params );
            app.parent_app.m.Redraw_fields();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            plot_params = Plot_params();
            app.parent_app.m.Set_plot_params( plot_params );
            app.Load_values();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Параметры отображения';
            app.CheckBox_imon = uicheckbox(app.UIFigure);
            app.CheckBox_imon.Text = 'Изображения';
            app.CheckBox_imon.Value = true;
            app.CheckBox_gridon = uicheckbox(app.UIFigure);
            app.CheckBox_gridon.Text = 'PIV сетка';
            app.CheckBox_quiver_on_piv = uicheckbox(app.UIFigure);
            app.CheckBox_quiver_on_piv.Text = 'Векторные поля исходные';
            app.CheckBox_pcoloron = uicheckbox(app.UIFigure);
            app.CheckBox_pcoloron.Text = 'Скалярные значения скорости';
            app.DropDown_pcolor_value = uidropdown(app.UIFigure);
            app.DropDown_pcolor_value.Items = {'u', 'v', 'V'};
            app.DropDown_pcolor_value.Value = 'u';
            app.ButtonGroup_im_select = uibuttongroup(app.UIFigure);
            app.Button__im_select_initial = uiradiobutton(app.ButtonGroup_im_select);
            app.Button__im_select_initial.Text = 'Исходные';
            app.Button__im_select_processed = uiradiobutton(app.ButtonGroup_im_select);
            app.Button__im_select_processed.Text = 'После препроцессинга';
            app.Button__im_select_processed.Value = true;
            app.CheckBox__quiver_dir_only = uicheckbox(app.UIFigure);
            app.CheckBox__quiver_dir_only.Text = 'Только направление';
            app.CheckBox_pcolor_caxis_auto = uicheckbox(app.UIFigure);
            app.CheckBox_pcolor_caxis_auto.Text = 'Пределы автоматически';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Символ';
            app.EditField_grid_s = uieditfield(app.UIFigure, 'text');
            app.EditField_grid_s.Value = '+';
            app.Spinner_3Label = uilabel(app.UIFigure);
            app.Spinner_3Label.Text = 'Размер символа';
            app.Spinner_grid_ssize = uispinner(app.UIFigure);
            app.Spinner_grid_ssize.Limits = [0 Inf];
            app.Spinner_grid_ssize.Value = 5;
            app.ColormapEditFieldLabel = uilabel(app.UIFigure);
            app.ColormapEditFieldLabel.Text = 'Colormap';
            app.ColormapEditField_im_cm = uieditfield(app.UIFigure, 'text');
            app.ColormapEditField_im_cm.Value = 'jet';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Масштаб';
            app.EditField_q_scale = uieditfield(app.UIFigure, 'numeric');
            app.EditField_q_scale.Value = 10;
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = 'Мин';
            app.EditField_p_cl_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'Макс';
            app.EditField_p_cl_2 = uieditfield(app.UIFigure, 'numeric');
            app.Button_apply = uibutton(app.UIFigure, 'push');
            app.Button_apply.ButtonPushedFcn = createCallbackFcn(app, @Button_applyPushed, true);
            app.Button_apply.Text = 'Применить';
            app.Button_cancel = uibutton(app.UIFigure, 'push');
            app.Button_cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_cancelPushed, true);
            app.Button_cancel.Text = 'Отмена';
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = 'Цвет';
            app.EditField_g_c = uieditfield(app.UIFigure, 'text');
            app.EditField_g_c.Value = 'r';
            app.CheckBox_p_cbon = uicheckbox(app.UIFigure);
            app.CheckBox_p_cbon.Text = 'Показывать сolorbar';
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = 'Цвет';
            app.EditField_q_c_piv = uieditfield(app.UIFigure, 'text');
            app.EditField_q_c_piv.Value = 'r';
            app.EditField_3Label_3 = uilabel(app.UIFigure);
            app.EditField_3Label_3.Text = 'Цвет';
            app.EditField_q_c_fi = uieditfield(app.UIFigure, 'text');
            app.EditField_q_c_fi.Value = 'r';
            app.ShadingDropDownLabel = uilabel(app.UIFigure);
            app.ShadingDropDownLabel.Text = 'Shading';
            app.ShadingDropDown_pcolor_shading = uidropdown(app.UIFigure);
            app.ShadingDropDown_pcolor_shading.Items = {'faceted', 'flat', 'interp'};
            app.ShadingDropDown_pcolor_shading.Value = 'interp';
            app.ColormapLabel = uilabel(app.UIFigure);
            app.ColormapLabel.Text = 'Colormap';
            app.ShadingDropDown_pcolor_colormap = uidropdown(app.UIFigure);
            app.ShadingDropDown_pcolor_colormap.Items = {};
            app.ShadingDropDown_pcolor_colormap.Value = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Прореживание';
            app.Spinner_quiver_step = uispinner(app.UIFigure);
            app.Spinner_quiver_step.Limits = [1 Inf];
            app.Spinner_quiver_step.Value = 1;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ShadingDropDown_pcolor_source = uidropdown(app.UIFigure);
            app.ShadingDropDown_pcolor_source.Items = {'piv', 'fi', 'mean'};
            app.ShadingDropDown_pcolor_source.Value = 'piv';
            app.CheckBox_quiver_on_fi = uicheckbox(app.UIFigure);
            app.CheckBox_quiver_on_fi.Text = 'Векторные поля после фильтрации и интерполяции';
            app.grid_show_status = uicheckbox(app.UIFigure);
            app.grid_show_status.Text = 'Показывать статус';
            app.grid_show_status_filtering = uicheckbox(app.UIFigure);
            app.grid_show_status_filtering.Text = 'Показывать статус фильтрации';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сбросить';
            app.quiver_on_mean = uicheckbox(app.UIFigure);
            app.quiver_on_mean.Text = 'Векторные поля: среднее';
            app.EditField_3Label_4 = uilabel(app.UIFigure);
            app.EditField_3Label_4.Text = 'Цвет';
            app.quiver_color_mean = uieditfield(app.UIFigure, 'text');
            app.quiver_color_mean.Value = 'r';
            app.plot_in_scale = uicheckbox(app.UIFigure);
            app.plot_in_scale.Text = 'Строить в размерных величинах';
            app.show_grid = uicheckbox(app.UIFigure);
            app.show_grid.Text = 'Показывать сетку';
            app.show_ticks = uicheckbox(app.UIFigure);
            app.show_ticks.Text = 'Показывать метки на осях';
            app.show_title = uicheckbox(app.UIFigure);
            app.show_title.Text = 'Показывать подпись';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Значение';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Источник';
            app.cb_grid_show_windows = uicheckbox(app.UIFigure);
            app.cb_grid_show_windows.Text = 'Окна';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_plot(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end