classdef PIV_db_process < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Button_start_one           matlab.ui.control.Button
        Button_start_all           matlab.ui.control.Button
        Button_stop                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        Label_info_1               matlab.ui.control.Label
        CheckBox_plot              matlab.ui.control.CheckBox
        Label_info_2               matlab.ui.control.Label
        CheckBox_drawnow           matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
        Spinner_drawnow_framestep  matlab.ui.control.Spinner
        Label_2                    matlab.ui.control.Label
        Spinner_plot_framestep     matlab.ui.control.Spinner
        Button_start_unprocessed   matlab.ui.control.Button
        Button_start_one_2         matlab.ui.control.Button
        Button_fi                  matlab.ui.control.Button
        Do_fi_after_piv            matlab.ui.control.CheckBox
        autosave                   matlab.ui.control.CheckBox
        Label_3                    matlab.ui.control.Label
        autosave_period            matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        parent_app
        
        flag_run_PIV = false;
        iteration_time_full = nan;
        time_left_min = 0;
    end
    
    methods (Access = private)
        function Update_info(app)
            app.parent_app.m.ax_process = app.UIAxes;
            app.parent_app.m.Redraw_process('piv_processor');
            app.Label_info_1.Text = app.parent_app.m.Process_piv__Get_info_progress();
            app.Label_info_2.Text = app.parent_app.m.Process_piv__Get_info();
            drawnow();
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
            app.Update_info();
        end

        % Button pushed function: Button_start_one
        function Button_start_onePushed(app, event)
            app.parent_app.m.Process_piv_current();            
            app.Update_info();
            app.parent_app.m.Redraw_fields();
        end

        % Button pushed function: Button_start_all
        function Button_start_allPushed(app, event)
            if app.flag_run_PIV, return; end %если уже выполняется не запускать еще раз
            app.flag_run_PIV = true;
            app.parent_app.m.Process_piv_reset();
            app.parent_app.m.Add_to_log('Вычисление PIV начато');
            for ti = 1 : app.parent_app.m.ti_count
                app.parent_app.m.Go_to_ti(ti);
                app.parent_app.m.Process_piv_current();
                % перестроить поля
                if      app.CheckBox_plot.Value &&...
                        rem(ti, app.Spinner_plot_framestep.Value) == 0
                    app.parent_app.m.Redraw_fields();
                    app.parent_app.init_gui();
                end
                if      app.CheckBox_drawnow.Value &&...
                        rem(ti, app.Spinner_drawnow_framestep.Value) == 0
                    app.Update_info();
                    drawnow();                % прерваться на перестроение ГУЯ
                end
                % выйти если нажата кнопка
                if ~app.flag_run_PIV
                    app.parent_app.m.Add_to_log('Вычисление PIV прервано');
                    app.Label_info_2.Text = 'Вычисление PIV прервано';
                    return;
                end
                %автосохранение
                if      app.autosave.Value &&...
                        rem(ti, app.autosave_period.Value) == 0
                    app.parent_app.m.Proj_save();
                end
            end
            app.flag_run_PIV = false;
            app.Update_info();
            app.parent_app.m.Add_to_log('Вычисление PIV закончено');
            app.Label_info_2.Text = 'Вычисление PIV закончено';
            drawnow();
            
            if app.Do_fi_after_piv.Value
                app.parent_app.m.Process_fi_all();
                app.parent_app.m.Redraw_fields();
            end
        end

        % Button pushed function: Button_stop
        function Button_stopPushed(app, event)
            app.flag_run_PIV = false; % остановить вычисления
        end

        % Button pushed function: Button_start_unprocessed
        function Button_start_unprocessedPushed(app, event)
            if app.flag_run_PIV, return; end %если уже выполняется не запускать еще раз
            app.flag_run_PIV = true;
            app.parent_app.m.Add_to_log('Вычисление PIV начато');
            ti_arr = find(~app.parent_app.m.Process_piv__is_processed_arr);
            for ti_arr_i = 1:numel(ti_arr)
                ti = ti_arr(ti_arr_i);
                app.parent_app.m.Go_to_ti(ti);
                app.parent_app.m.Process_piv_current();
                % перестроить поля
                if      app.CheckBox_plot.Value &&...
                        rem(ti, app.Spinner_plot_framestep.Value) == 0
                    app.parent_app.m.Redraw_fields();
                    app.parent_app.init_gui();
                end
                if      app.CheckBox_drawnow.Value &&...
                        rem(ti, app.Spinner_drawnow_framestep.Value) == 0
                    app.Update_info();
                    drawnow();                % прерваться на перестроение ГУЯ
                end
                % выйти если нажата кнопка
                if ~app.flag_run_PIV
                    app.parent_app.m.Add_to_log('Вычисление PIV прервано');
                    app.Label_info_2.Text = 'Вычисление PIV прервано';
                    return;
                end
                %автосохранение
                if      app.autosave.Value &&...
                        rem(ti, app.autosave_period.Value) == 0
                    app.parent_app.m.Proj_save();
                end
            end
            app.flag_run_PIV = false;
            app.Update_info();
            app.parent_app.m.Add_to_log('Вычисление PIV закончено');
            app.Label_info_2.Text = 'Вычисление PIV закончено';
            drawnow();
            
            if app.Do_fi_after_piv.Value
                app.parent_app.m.Process_fi_all();
                app.parent_app.m.Redraw_fields();
            end
        end

        % Button pushed function: Button_start_one_2
        function Button_start_one_2Pushed(app, event)
            if app.flag_run_PIV, return; end %если уже выполняется не запускать еще раз
            app.flag_run_PIV = true;
            ti = app.parent_app.m.current_ti;
            while app.flag_run_PIV
                app.parent_app.m.Process_piv_current();
                % перестроить поля
                if      app.CheckBox_plot.Value &&...
                        rem(ti, app.Spinner_plot_framestep.Value) == 0
                    app.parent_app.m.Redraw_fields();
                    app.parent_app.init_gui();
                end
                if      app.CheckBox_drawnow.Value &&...
                        rem(ti, app.Spinner_drawnow_framestep.Value) == 0
                    app.Update_info();
                    drawnow();                % прерваться на перестроение ГУЯ
                end
                % выйти если нажата кнопка
                if ~app.flag_run_PIV
                    app.parent_app.m.Add_to_log('Вычисление PIV прервано');
                    app.Label_info_2.Text = 'Вычисление PIV прервано';
                    break;
                end
                %автосохранение
                if      app.autosave.Value &&...
                        rem(ti, app.autosave_period.Value) == 0
                    app.parent_app.m.Proj_save();
                end
            end
            app.flag_run_PIV = false;
            app.Update_info();
            app.parent_app.m.Add_to_log('Вычисление PIV закончено');
            app.Label_info_2.Text = 'Вычисление PIV закончено';
            drawnow();
        end

        % Button pushed function: Button_fi
        function Button_fiPushed(app, event)
            app.parent_app.m.Process_fi_all();
            app.parent_app.m.Redraw_fields();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Вычисление полей';
            app.Button_start_one = uibutton(app.UIFigure, 'push');
            app.Button_start_one.ButtonPushedFcn = createCallbackFcn(app, @Button_start_onePushed, true);
            app.Button_start_one.Text = 'Вычислить для текущего кадра';
            app.Button_start_all = uibutton(app.UIFigure, 'push');
            app.Button_start_all.ButtonPushedFcn = createCallbackFcn(app, @Button_start_allPushed, true);
            app.Button_start_all.Text = 'Запустить с начала';
            app.Button_stop = uibutton(app.UIFigure, 'push');
            app.Button_stop.ButtonPushedFcn = createCallbackFcn(app, @Button_stopPushed, true);
            app.Button_stop.Text = 'Остановить';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Label_info_1 = uilabel(app.UIFigure);
            app.Label_info_1.Text = 'Info';
            app.CheckBox_plot = uicheckbox(app.UIFigure);
            app.CheckBox_plot.Text = 'Строить поля во время вычисления';
            app.CheckBox_plot.Value = true;
            app.Label_info_2 = uilabel(app.UIFigure);
            app.Label_info_2.Text = 'Info';
            app.CheckBox_drawnow = uicheckbox(app.UIFigure);
            app.CheckBox_drawnow.Text = 'Обновлять интерфейс во время вычисления';
            app.CheckBox_drawnow.Value = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Период обновления';
            app.Spinner_drawnow_framestep = uispinner(app.UIFigure);
            app.Spinner_drawnow_framestep.Limits = [1 Inf];
            app.Spinner_drawnow_framestep.Value = 1;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Период обновления';
            app.Spinner_plot_framestep = uispinner(app.UIFigure);
            app.Spinner_plot_framestep.Limits = [1 Inf];
            app.Spinner_plot_framestep.Value = 1;
            app.Button_start_unprocessed = uibutton(app.UIFigure, 'push');
            app.Button_start_unprocessed.ButtonPushedFcn = createCallbackFcn(app, @Button_start_unprocessedPushed, true);
            app.Button_start_unprocessed.Text = 'Запустить для необработанных';
            app.Button_start_one_2 = uibutton(app.UIFigure, 'push');
            app.Button_start_one_2.ButtonPushedFcn = createCallbackFcn(app, @Button_start_one_2Pushed, true);
            app.Button_start_one_2.Text = 'Вычислять для текущего кадра';
            app.Button_fi = uibutton(app.UIFigure, 'push');
            app.Button_fi.ButtonPushedFcn = createCallbackFcn(app, @Button_fiPushed, true);
            app.Button_fi.Text = 'Провести фильтрацию и интерполяцию';
            app.Do_fi_after_piv = uicheckbox(app.UIFigure);
            app.Do_fi_after_piv.Text = 'Произвести фильтрацию и интерполяцию после PIV';
            app.Do_fi_after_piv.Value = true;
            app.autosave = uicheckbox(app.UIFigure);
            app.autosave.Text = 'Автоматически сохранять проект';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Период сохранения';
            app.autosave_period = uispinner(app.UIFigure);
            app.autosave_period.Limits = [1 Inf];
            app.autosave_period.Value = 1000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_process(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end