classdef PIV_db_proj_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        new_project            matlab.ui.Figure
        Label                  matlab.ui.control.Label
        im_path                matlab.ui.control.EditField
        Label_2                matlab.ui.control.Label
        comment                matlab.ui.control.TextArea
        im_path_b              matlab.ui.control.Button
        Button_ok              matlab.ui.control.Button
        Button_cancel          matlab.ui.control.Button
        fpsEditFieldLabel      matlab.ui.control.Label
        fpsEditField           matlab.ui.control.NumericEditField
        scalepxEditFieldLabel  matlab.ui.control.Label
        scalepxEditField       matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        proj_path              matlab.ui.control.EditField
        proj_path_b            matlab.ui.control.Button
        pxEditFieldLabel       matlab.ui.control.Label
        zx                     matlab.ui.control.NumericEditField
        zy                     matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        im_ext                 matlab.ui.control.EditField
    end


    properties (Access = private)
        parent_app % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;              
            m = app.parent_app.m;
            if ~isempty(app.parent_app.m.core)
                app.proj_path.Value = m.proj_path;
                app.im_path.Value = m.p.il.images_folder;
                app.im_ext.Value = m.p.il.images_ext;
                rp = m.p.rp;
                app.comment.Value  = rp.comment;
                app.fpsEditField.Value = rp.fps;
                app.scalepxEditField.Value = rp.scale;
                app.zx.Value = rp.zero_pos_px(1);
                app.zy.Value = rp.zero_pos_px(2);
            end
        end

        % Button pushed function: im_path_b
        function im_path_bPushed(app, event)
            selpath = uigetdir(app.im_path.Value);
            if selpath ~= 0 
                app.im_path.Value = selpath;
            end
        end

        % Button pushed function: Button_cancel
        function Button_cancelPushed(app, event)
            delete(app);
        end

        % Button pushed function: Button_ok
        function Button_okPushed(app, event)
            m = app.parent_app.m;
            m.Proj_new(...
                app.im_path.Value, ...
                app.im_ext.Value, ...
                app.proj_path.Value);
            rp = Record_params();
            rp.comment = app.comment.Value;
            rp.fps = app.fpsEditField.Value;
            rp.scale = app.scalepxEditField.Value;
            rp.zero_pos_px = [ app.zx.Value app.zy.Value ];
            m.Set_rp_params(rp);   
            app.parent_app.init_gui();
            delete(app);
        end

        % Button pushed function: proj_path_b
        function proj_path_bPushed(app, event)
            [file,path] = uiputfile('*.proj');
            if file == 0, return; end
            selpath = fullfile(path,file);
            app.proj_path.Value = selpath;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.new_project = uifigure;
            app.new_project.Name = 'Параметры проекта';
            app.Label = uilabel(app.new_project);
            app.Label.Text = 'Путь к папке с изображениями';
            app.im_path = uieditfield(app.new_project, 'text');
            app.Label_2 = uilabel(app.new_project);
            app.Label_2.Text = 'Описание';
            app.comment = uitextarea(app.new_project);
            app.im_path_b = uibutton(app.new_project, 'push');
            app.im_path_b.ButtonPushedFcn = createCallbackFcn(app, @im_path_bPushed, true);
            app.im_path_b.Text = 'Выбрать';
            app.Button_ok = uibutton(app.new_project, 'push');
            app.Button_ok.ButtonPushedFcn = createCallbackFcn(app, @Button_okPushed, true);
            app.Button_ok.Text = 'Создать проект';
            app.Button_cancel = uibutton(app.new_project, 'push');
            app.Button_cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_cancelPushed, true);
            app.Button_cancel.Text = 'Отмена';
            app.fpsEditFieldLabel = uilabel(app.new_project);
            app.fpsEditFieldLabel.Text = 'fps (к/с)';
            app.fpsEditField = uieditfield(app.new_project, 'numeric');
            app.fpsEditField.Value = 1;
            app.scalepxEditFieldLabel = uilabel(app.new_project);
            app.scalepxEditFieldLabel.Text = 'scale (мкм/px)';
            app.scalepxEditField = uieditfield(app.new_project, 'numeric');
            app.scalepxEditField.Value = 1;
            app.Label_3 = uilabel(app.new_project);
            app.Label_3.Text = 'Путь к файлу проекта';
            app.proj_path = uieditfield(app.new_project, 'text');
            app.proj_path_b = uibutton(app.new_project, 'push');
            app.proj_path_b.ButtonPushedFcn = createCallbackFcn(app, @proj_path_bPushed, true);
            app.proj_path_b.Text = 'Выбрать';
            app.pxEditFieldLabel = uilabel(app.new_project);
            app.pxEditFieldLabel.Text = 'Начало координат (px)';
            app.zx = uieditfield(app.new_project, 'numeric');
            app.zy = uieditfield(app.new_project, 'numeric');
            app.Label_4 = uilabel(app.new_project);
            app.Label_4.Text = 'Расширение изображений';
            app.im_ext = uieditfield(app.new_project, 'text');
            app.im_ext.Value = 'jpg';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PIV_db_proj_new(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.new_project)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.new_project)
        end
    end
end