classdef PIV_main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        Menu_proj_new             matlab.ui.container.Menu
        Menu_proj_load            matlab.ui.container.Menu
        Menu_proj_save            matlab.ui.container.Menu
        Menu_proj_saveas          matlab.ui.container.Menu
        Menu_proj_edit            matlab.ui.container.Menu
        Menu_7                    matlab.ui.container.Menu
        Menu_ipp                  matlab.ui.container.Menu
        Menu_pg                   matlab.ui.container.Menu
        Menu_pf                   matlab.ui.container.Menu
        Menu_pp                   matlab.ui.container.Menu
        Menu_pfi                  matlab.ui.container.Menu
        Menu_plot                 matlab.ui.container.Menu
        Run                       matlab.ui.container.Menu
        RunPIV                    matlab.ui.container.Menu
        Menu_24                   matlab.ui.container.Menu
        Menu_25                   matlab.ui.container.Menu
        FrameSlider_frame_number  matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        Spinner_frame_number      matlab.ui.control.Spinner
        Label_2                   matlab.ui.control.Label
        Spinner_ti                matlab.ui.control.Spinner
        Play                      matlab.ui.control.Button
        Stop                      matlab.ui.control.Button
        Play_2                    matlab.ui.control.Button
        play_loop                 matlab.ui.control.CheckBox
        Readraw                   matlab.ui.control.Button
        Panel_sp                  matlab.ui.container.Panel
        DropDown_sp_add_pxm       matlab.ui.control.DropDown
        Button_sp_add             matlab.ui.control.Button
        xyEditField_y             matlab.ui.control.NumericEditField
        xyLabel                   matlab.ui.control.Label
        xyEditField_x             matlab.ui.control.NumericEditField
        Button_sp_delete_all      matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        m PIV_main
        fig_fields
        
        flag_play = false;
        
        %Диалогбоксы
        db_proj_new  PIV_db_proj_new
        db_proj_edit PIV_db_proj_edit
        db_piv_grid  PIV_db_piv_grid
        db_piv_params PIV_db_piv_params
        db_ipp PIV_db_ipp
        db_piv_frames PIV_db_piv_frames
        db_plot PIV_db_plot
        db_process PIV_db_process
        db_fi_params PIV_db_fi_params
        db_export  PIV_db_export
        db_analyze PIV_db_analyze
    end
    
    methods (Access = public)
        function init_gui(app) % привести гуй в соответствие с настройками. весь бэкенд должен быть инициализирован на этом этапе.
            app.UIFigure.Name = app.m.proj_path;            
            if isempty(app.m.core), return; end
            app.FrameSlider_frame_number.Limits  = [1 app.m.im_count];
            app.Spinner_frame_number.Limits      = [1 app.m.im_count];
            if (app.m.ti_count > 1)
                app.Spinner_ti.Enable = true;
                app.Spinner_ti.Limits      = [1 app.m.ti_count];
            else
                app.Spinner_ti.Enable = false;
            end
            app.Spinner_frame_number.Value      = app.m.current_frame;
            app.FrameSlider_frame_number.Value  = app.m.current_frame;
            if ~isnan(app.m.current_ti)
                app.Spinner_ti.Value      = app.m.current_ti;
            end
        end
        
        function proj_load(app, proj_path)
            app.add_to_log(sprintf('Загружается проект из %s', proj_path));
            app.P_path = proj_path;
            f = load(app.P_path,'-mat');
            app.P = f.Proj;
            app.init();
            app.add_to_log(sprintf('Загружен проект из %s', proj_path));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, piv_main)
            app.m = piv_main;
            %             piv_main.gui_app = app;
            app.m.Redraw_fields();
            app.init_gui();
        end

        % Value changed function: FrameSlider_frame_number
        function FrameSlider_frame_numberValueChanged(app, event)
            value = round(app.FrameSlider_frame_number.Value);
            app.m.Go_to_frame(value);
            app.m.Redraw_fields();
            app.init_gui();            
        end

        % Menu selected function: Menu_pg
        function Menu_pgSelected(app, event)
            app.db_piv_grid = PIV_db_piv_grid(app);
        end

        % Menu selected function: Menu_proj_new
        function Menu_proj_newSelected(app, event)
            app.db_proj_new = PIV_db_proj_new(app);
        end

        % Menu selected function: Menu_proj_saveas
        function Menu_proj_saveasSelected(app, event)
            [file,path] = uiputfile('*.proj');
            if file == 0, return; end
            selpath = fullfile(path,file);
            app.m.proj_path = selpath;
            app.m.Proj_save(app.m.proj_path);
            app.m.Add_to_log(sprintf('Проект сохренен в %s', app.m.proj_path));
            app.init_gui();
        end

        % Menu selected function: Menu_proj_load
        function Menu_proj_loadSelected(app, event)
            [file,path] = uigetfile('*.proj');
            if file == 0, return; end
            file_path = fullfile(path,file);
            app.m.Proj_load(file_path);
            app.init_gui();
        end

        % Menu selected function: Menu_proj_save
        function Menu_proj_saveSelected(app, event)
            if isempty(app.m.proj_path)
                [file,path] = uiputfile('*.proj');
                if file == 0, return; end
                selpath = fullfile(path,file);
                app.m.proj_path = selpath;
            end
            app.m.Proj_save(app.m.proj_path);
            app.m.Add_to_log(sprintf('Проект сохренен в %s', app.m.proj_path));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %             %save
            %             msg = 'Сохранить проект перед закрытием программы?';
            %             title = 'Выход из программы';
            %             selection = uiconfirm(app.UIFigure,msg,title,...
            %                 'Options',{'Сохранить','Не сохранять','Отмена'},...
            %                 'DefaultOption',1,'CancelOption',3);
            %             switch (selection)
            %                 case 'Сохранить'
            %                     if isempty(app.m.proj_path)
            %                         file_path = uiputfile('*.piv_aura_proj');
            %                         if file_path == 0, return; end
            %                         app.m.proj_path = file_path;
            %                     end
            %                     app.m.proj_save(app.m.proj_path);
            %                     app.m.add_to_log(sprintf('Проект сохренен в %s', app.m.proj_path));
            %                 case 'Отмена'
            %                     return;
            %             end
            delete(app.db_proj_new);
            delete(app.db_proj_edit);
            delete(app.db_piv_grid);
            delete(app.db_piv_params);
            % TODO провеить все ли на месте?            
            if ishandle(app.m) && ishandle(app.m.fig_fields)
                close(app.m.fig_fields);
            end
            delete(app);
        end

        % Menu selected function: Menu_proj_edit
        function Menu_proj_editSelected(app, event)
            app.db_proj_edit = PIV_db_proj_edit(app);
        end

        % Menu selected function: Menu_pp
        function Menu_ppSelected(app, event)
            app.db_piv_params = PIV_db_piv_params(app);
        end

        % Callback function
        function Menu_9Selected(app, event)
            app.run_current_frame();
        end

        % Value changed function: Spinner_frame_number
        function Spinner_frame_numberValueChanged(app, event)
            value = round(app.Spinner_frame_number.Value);
            app.m.Go_to_frame(value);
            app.m.Redraw_fields();
            app.init_gui();
        end

        % Menu selected function: Menu_ipp
        function Menu_ippSelected(app, event)
            app.db_ipp = PIV_db_ipp(app);
        end

        % Menu selected function: Menu_pf
        function Menu_pfSelected(app, event)
            app.db_piv_frames = PIV_db_piv_frames(app);
        end

        % Value changed function: Spinner_ti
        function Spinner_tiValueChanged(app, event)
            value = app.Spinner_ti.Value;
            app.m.Go_to_ti(value);
            app.m.Redraw_fields();
            app.init_gui();
        end

        % Menu selected function: Menu_plot
        function Menu_plotSelected(app, event)
            app.db_plot = PIV_db_plot(app);
        end

        % Menu selected function: RunPIV
        function RunPIVMenuSelected(app, event)
            app.db_process = PIV_db_process(app);
        end

        % Menu selected function: Menu_pfi
        function Menu_pfiSelected(app, event)
            app.db_fi_params = PIV_db_fi_params(app);
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            if app.flag_play, return; end
            app.flag_play = true;
            while(app.flag_play)
                frame_i = app.m.current_frame;
                if frame_i >= app.m.im_count
                    if app.play_loop.Value
                        frame_i = 1;
                    else
                        break;
                    end
                end
                app.m.Go_to_frame( frame_i + 1 );
                app.m.Redraw_fields();
                app.init_gui();
                drawnow();
            end
            app.flag_play = false;
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.flag_play = false;
        end

        % Button pushed function: Play_2
        function Play_2ButtonPushed(app, event)
            if app.flag_play, return; end
            app.flag_play = true;
            while(app.flag_play)
                ti = app.m.current_ti;
                if ti >= app.m.ti_count
                    if app.play_loop.Value
                        ti = 1;
                    else
                        break;
                    end
                end
                app.m.Go_to_ti( ti + 1 );
                app.m.Redraw_fields();
                app.init_gui();
                drawnow();
            end
            app.flag_play = false;
        end

        % Callback function
        function Stop_2ButtonPushed(app, event)
            app.flag_play = false;
        end

        % Menu selected function: Menu_24
        function Menu_24Selected(app, event)
            app.db_analyze = PIV_db_analyze(app);
        end

        % Menu selected function: Menu_25
        function Menu_25Selected(app, event)
            app.db_export = PIV_db_export(app);
        end

        % Button pushed function: Readraw
        function ReadrawButtonPushed(app, event)
            app.m.Redraw_fields();
        end

        % Button pushed function: Button_sp_add
        function Button_sp_addPushed(app, event)
            x = app.xyEditField_x.Value;
            y = app.xyEditField_y.Value;
            if strcmp(app.DropDown_sp_add_pxm.Value, 'px')
                app.m.Add_selected_point_px(x,y);
            else
                app.m.Add_selected_point_m(x,y);
            end
            app.m.Redraw_fields();
        end

        % Button pushed function: Button_sp_delete_all
        function Button_sp_delete_allPushed(app, event)
            app.m.Clear_selected_point_m();
            app.m.Redraw_fields();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Основное окно';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Проект';
            app.Menu_proj_new = uimenu(app.Menu);
            app.Menu_proj_new.MenuSelectedFcn = createCallbackFcn(app, @Menu_proj_newSelected, true);
            app.Menu_proj_new.Text = 'Новый';
            app.Menu_proj_load = uimenu(app.Menu);
            app.Menu_proj_load.MenuSelectedFcn = createCallbackFcn(app, @Menu_proj_loadSelected, true);
            app.Menu_proj_load.Text = 'Открыть';
            app.Menu_proj_save = uimenu(app.Menu);
            app.Menu_proj_save.MenuSelectedFcn = createCallbackFcn(app, @Menu_proj_saveSelected, true);
            app.Menu_proj_save.Text = 'Сохранить';
            app.Menu_proj_saveas = uimenu(app.Menu);
            app.Menu_proj_saveas.MenuSelectedFcn = createCallbackFcn(app, @Menu_proj_saveasSelected, true);
            app.Menu_proj_saveas.Text = 'Сохранить как';
            app.Menu_proj_edit = uimenu(app.Menu);
            app.Menu_proj_edit.MenuSelectedFcn = createCallbackFcn(app, @Menu_proj_editSelected, true);
            app.Menu_proj_edit.Text = 'Параметры проекта';
            app.Menu_7 = uimenu(app.UIFigure);
            app.Menu_7.Text = 'Параметры';
            app.Menu_ipp = uimenu(app.Menu_7);
            app.Menu_ipp.MenuSelectedFcn = createCallbackFcn(app, @Menu_ippSelected, true);
            app.Menu_ipp.Text = 'Параметры изображений';
            app.Menu_pg = uimenu(app.Menu_7);
            app.Menu_pg.MenuSelectedFcn = createCallbackFcn(app, @Menu_pgSelected, true);
            app.Menu_pg.Text = 'Параметры сетки';
            app.Menu_pf = uimenu(app.Menu_7);
            app.Menu_pf.MenuSelectedFcn = createCallbackFcn(app, @Menu_pfSelected, true);
            app.Menu_pf.Text = 'Параметры кадров';
            app.Menu_pp = uimenu(app.Menu_7);
            app.Menu_pp.MenuSelectedFcn = createCallbackFcn(app, @Menu_ppSelected, true);
            app.Menu_pp.Text = 'Параметры PIV';
            app.Menu_pfi = uimenu(app.Menu_7);
            app.Menu_pfi.MenuSelectedFcn = createCallbackFcn(app, @Menu_pfiSelected, true);
            app.Menu_pfi.Text = 'Фильтрация и интерполяция';
            app.Menu_plot = uimenu(app.Menu_7);
            app.Menu_plot.MenuSelectedFcn = createCallbackFcn(app, @Menu_plotSelected, true);
            app.Menu_plot.Text = 'Отображение';
            app.Run = uimenu(app.UIFigure);
            app.Run.Text = 'Инструменты';
            app.RunPIV = uimenu(app.Run);
            app.RunPIV.MenuSelectedFcn = createCallbackFcn(app, @RunPIVMenuSelected, true);
            app.RunPIV.Text = 'Вычисление полей';
            app.Menu_24 = uimenu(app.Run);
            app.Menu_24.MenuSelectedFcn = createCallbackFcn(app, @Menu_24Selected, true);
            app.Menu_24.Text = 'Анализ полей';
            app.Menu_25 = uimenu(app.Run);
            app.Menu_25.MenuSelectedFcn = createCallbackFcn(app, @Menu_25Selected, true);
            app.Menu_25.Text = 'Экспорт';
            app.FrameSlider_frame_number = uislider(app.UIFigure);
            app.FrameSlider_frame_number.Limits = [0 7];
            app.FrameSlider_frame_number.ValueChangedFcn = createCallbackFcn(app, @FrameSlider_frame_numberValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Кадр:';
            app.Spinner_frame_number = uispinner(app.UIFigure);
            app.Spinner_frame_number.ValueChangedFcn = createCallbackFcn(app, @Spinner_frame_numberValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Поле';
            app.Spinner_ti = uispinner(app.UIFigure);
            app.Spinner_ti.ValueChangedFcn = createCallbackFcn(app, @Spinner_tiValueChanged, true);
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Text = 'Старт';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = 'Стоп';
            app.Play_2 = uibutton(app.UIFigure, 'push');
            app.Play_2.ButtonPushedFcn = createCallbackFcn(app, @Play_2ButtonPushed, true);
            app.Play_2.Text = 'Старт';
            app.play_loop = uicheckbox(app.UIFigure);
            app.play_loop.Text = 'Циклическое воспроизведение';
            app.Readraw = uibutton(app.UIFigure, 'push');
            app.Readraw.ButtonPushedFcn = createCallbackFcn(app, @ReadrawButtonPushed, true);
            app.Readraw.Text = 'Перестроить текущий';
            app.Panel_sp = uipanel(app.UIFigure);
            app.Panel_sp.Title = 'Выбор точек';
            app.DropDown_sp_add_pxm = uidropdown(app.Panel_sp);
            app.DropDown_sp_add_pxm.Items = {'px', 'м', ''};
            app.DropDown_sp_add_pxm.Value = 'px';
            app.Button_sp_add = uibutton(app.Panel_sp, 'push');
            app.Button_sp_add.ButtonPushedFcn = createCallbackFcn(app, @Button_sp_addPushed, true);
            app.Button_sp_add.Text = 'Добавить';
            app.xyEditField_y = uieditfield(app.Panel_sp, 'numeric');
            app.xyLabel = uilabel(app.Panel_sp);
            app.xyLabel.Text = 'x, y';
            app.xyEditField_x = uieditfield(app.Panel_sp, 'numeric');
            app.Button_sp_delete_all = uibutton(app.Panel_sp, 'push');
            app.Button_sp_delete_all.ButtonPushedFcn = createCallbackFcn(app, @Button_sp_delete_allPushed, true);
            app.Button_sp_delete_all.Text = {'Удалить все'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIV_main_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end